classdef ecgPlotScreenUser < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        BPMHeartRateValue_3  matlab.ui.control.Label
        BPMHeartRateValue_2  matlab.ui.control.Label
        Image_4              matlab.ui.control.Image
        Image_3              matlab.ui.control.Image
        HeartRateLabel       matlab.ui.control.Label
        errorMessageUser     matlab.ui.control.Label
        ImageWarningUser     matlab.ui.control.Image
        BPMHeartRateValue    matlab.ui.control.Label
        Image_2              matlab.ui.control.Image
        Image                matlab.ui.control.Image
        UIAxes2              matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function plotECG(comp)

            function showWarning()
                comp.errorMessageUser.Visible = 'on';
                comp.ImageWarningUser.Visible = 'on';
            end
        load('101m.mat','val');
        ECGsignal = val(1,:) - mean(val(1,:));
        Fs = 360; t = (0:length(ECGsignal)-1) / Fs;
        threshold = max(ECGsignal)/3;
        
        % QRS Detection
        [pks,locs] = findpeaks(ECGsignal, 'MinPeakHeight', threshold, 'MinPeakDistance', Fs*0.2);
        qrsWidth = round(0.1 * Fs);
        q_locs = max(locs - qrsWidth / 2, 1); 
        s_locs = min(locs + qrsWidth / 2, length(ECGsignal));
        
        % Arrhythmia Analysis
        RR_intervals = diff(locs)/Fs;
        heart_rates = 60 ./ RR_intervals;
        
        % ST Segment and PR Interval Analysis
        st_duration = s_locs - q_locs;
        pr_duration = diff(locs) / Fs;
        plotECG(t, ECGsignal, locs, q_locs, s_locs, pks, 'ECG Signal with QRS Complex Annotated',Fs);

        disp(['Average Heart Rate: ', num2str(mean(heart_rates)), ' BPM']);
        
        analyzeHeartRate(locs, Fs);
        analyzeArrhythmia(t, locs, RR_intervals);
        analyzeSTPR(t, locs, st_duration, pr_duration, Fs);

        t_wave_abnormalities = analyzeTWave(ECGsignal, t, locs, q_locs, Fs);

        p_wave_abnormalities = analyzePWave(ECGsignal, t, locs, q_locs, s_locs, Fs);
        
        displayResults(t, locs, t_wave_abnormalities, p_wave_abnormalities);

    
        function plotECG(t, ECGsignal, locs, q_locs, s_locs, pks, titleText, Fs)

    samplePeriod = 1 / Fs; % Time period of one sample
    bufferSize = 10; % Number of samples to buffer
    bufferTime = samplePeriod * bufferSize; % Total time of buffered samples

    accumulatedLocs = []; % Initialize outside the loop to accumulate R peaks

    % Start with an empty plot
    heartRateHandle = plot(comp.UIAxes2, nan, nan, 'b'); 
    hold(comp.UIAxes2, 'on');
    comp.UIAxes2.XLabel.String = 'Time (s)';
    comp.UIAxes2.YLabel.String = 'Heart Rate (BPM)';
    comp.UIAxes2.Title.String = 'Heart Rate Over Time';

    % Define normal and abnormal heart rate thresholds
    normalLowerBound = 60;
    normalUpperBound = 100;

    % Add color-coded zones
    yLimit = [0, max([120, normalUpperBound + 20])]; % Extend y-axis limit if needed
    comp.UIAxes2.YLim = yLimit; % Set new y-axis limits

    % Normal heart rate zone (different color, e.g., light blue)
    fill(comp.UIAxes2, [0, max(t), max(t), 0], [normalLowerBound, normalLowerBound, normalUpperBound, normalUpperBound], 'g', 'FaceAlpha', 0.3, 'EdgeColor', 'none');
    
    % Lower red zone (heart rate too low)
    fill(comp.UIAxes2, [0, max(t), max(t), 0], [0, 0, normalLowerBound, normalLowerBound], 'r', 'FaceAlpha', 0.3, 'EdgeColor', 'none');
    
    % Upper red zone (heart rate too high)
    fill(comp.UIAxes2, [0, max(t), max(t), 0], [normalUpperBound, normalUpperBound, yLimit(2), yLimit(2)], 'r', 'FaceAlpha', 0.3, 'EdgeColor', 'none');

    for i = 1:bufferSize:length(ECGsignal)
        bufferEnd = min(i+bufferSize-1, length(ECGsignal));
        currentLocs = locs(locs >= i & locs <= bufferEnd);
        accumulatedLocs = [accumulatedLocs, currentLocs]; % Append new R peaks

        if ~isempty(accumulatedLocs)
            heart_rates = analyzeHeartRate(accumulatedLocs, Fs);
            timeHR = t(accumulatedLocs(2:end));       
            set(heartRateHandle, 'XData', timeHR, 'YData', heart_rates);
            latest_heart_rate = heart_rates(end); % Get the last value in the heart_rates array
            comp.BPMHeartRateValue.Text = num2str(latest_heart_rate);           
        end

        drawnow; 
        pause(bufferTime);
    end
    hold(comp.UIAxes2,"off");
end


%analyze heart rate
 function heart_rates = analyzeHeartRate(locs, Fs)
    if length(locs) > 1
        RR_intervals = diff(locs) / Fs;
        heart_rates = 60 ./ RR_intervals;
    else
        heart_rates = 0; % Return an empty array if not enough points to calculate heart rate
    end
end


%displayResults
function displayResults(t, locs, t_wave_abnormalities, p_wave_abnormalities)
    disp('T-wave Abnormalities:');
    for i = 1:length(t_wave_abnormalities)
        disp(['T-wave after QRS complex at ' num2str(t(locs(i + 1))) 's - ' t_wave_abnormalities{i}]);
    end

    disp('P-wave Abnormalities:');
    for i = 1:length(p_wave_abnormalities)
        disp(['P-wave before QRS complex at ' num2str(t(locs(i))) 's - ' p_wave_abnormalities{i}]);
    end
end

%analyzeArrhythmia
function analyzeArrhythmia(t, locs, RR_intervals)
    tachycardia_threshold = 100; % BPM
    bradycardia_threshold = 60; % BPM
    heart_rates = 60 ./ RR_intervals;

    tachycardia_indices = find(heart_rates > tachycardia_threshold);
    bradycardia_indices = find(heart_rates < bradycardia_threshold);
    rr_std = std(RR_intervals);
    rr_irregularity_threshold = 0.1; % Customize as needed

    if ~isempty(tachycardia_indices)
        disp(['Tachycardia detected at times: ', num2str(t(locs(tachycardia_indices)))]);
        showWarning();
    else
        disp('No tachycardia detected.');
    end

    if ~isempty(bradycardia_indices)
        disp(['Bradycardia detected at times: ', num2str(t(locs(bradycardia_indices)))]);
        showWarning();
    else
        disp('No bradycardia detected.');
    end

    if rr_std > rr_irregularity_threshold
        disp('Possible arrhythmia (atrial fibrillation) detected.');
        showWarning();
    else
        disp('No significant arrhythmia detected.');
    end
end

%analyzeSTPR
function analyzeSTPR(t, locs, st_duration, pr_duration, Fs)
            pr_abnormal_threshold = 0.2; % seconds
            st_abnormal_threshold = 0.1; % seconds
        
            for i = 1:length(pr_duration)
                if pr_duration(i) > pr_abnormal_threshold
                    disp(['At ' num2str(t(locs(i))) 's: PR interval prolonged (possible heart block)']);
                    showWarning();
                elseif pr_duration(i) < 0.12
                    disp(['At ' num2str(t(locs(i))) 's: PR interval too short (possible pre-excitation syndrome)']);
                    showWarning();
                end
            end
        
            for i = 1:length(st_duration)
                if st_duration(i) > st_abnormal_threshold * Fs
                    disp(['At ' num2str(t(locs(i))) 's: ST segment abnormal (possible myocardial ischemia)']);
                    showWarning();
                end
            end
end

% t_wave_abnormalities
function t_wave_abnormalities = analyzeTWave(ECGsignal, t, locs, q_locs, Fs)
    % Initialize cell array to store T-wave analysis results
    t_wave_abnormalities = cell(length(locs) - 1, 1);
    
    % Define parameters for T-wave morphology analysis (you can adjust these)
    t_wave_duration_threshold = 0.3; % seconds
    t_wave_amplitude_threshold = 0.1; % amplitude units (adjust as needed)
    
    for i = 2:(length(locs)-1) % Iterate up to the second-to-last QRS complex
        % Extract the T-wave segment between current QRS complex and next QRS complex
        t_wave_start_index = q_locs(i);
        t_wave_end_index = q_locs(i + 1);
        t_wave_segment = ECGsignal(t_wave_start_index:t_wave_end_index);
        
        % Measure T-wave duration and amplitude
        t_wave_duration = (t_wave_end_index - t_wave_start_index) / Fs;
        t_wave_amplitude = max(t_wave_segment) - min(t_wave_segment);
        
        % Check for T-wave inversion (example criteria)
        if t_wave_duration > t_wave_duration_threshold && t_wave_amplitude < -t_wave_amplitude_threshold
            t_wave_abnormalities{i-1} = 'T-wave inversion detected';
        else
            t_wave_abnormalities{i-1} = 'T-wave within normal limits';
        end
    end
end

% p_wave_abnormalities
function p_wave_abnormalities = analyzePWave(ECGsignal, t, locs, q_locs, s_locs, Fs)
    % Initialize cell array to store P-wave analysis results
    p_wave_abnormalities = cell(length(locs) - 1, 1);
    
    % Define parameters for P-wave morphology analysis (you can adjust these)
    p_wave_duration_threshold = 0.12; % seconds
    p_wave_amplitude_threshold = 0.1; % amplitude units (adjust as needed)
    
    for i = 2:(length(locs)-1) % Iterate up to the second-to-last QRS complex
        % Extract the P-wave segment between previous S-wave and current QRS complex
        p_wave_start_index = s_locs(i - 1);
        p_wave_end_index = q_locs(i);
        p_wave_segment = ECGsignal(p_wave_start_index:p_wave_end_index);
        
        % Measure P-wave duration and amplitude
        p_wave_duration = (p_wave_end_index - p_wave_start_index) / Fs;
        p_wave_amplitude = max(p_wave_segment) - min(p_wave_segment);
        
        % Implement your P-wave morphology analysis logic here
        % Example: Check for P-wave shape, amplitude, and duration
        
        % Interpret P-wave abnormalities based on your analysis criteria
        if p_wave_duration > p_wave_duration_threshold && p_wave_amplitude < -p_wave_amplitude_threshold
            p_wave_abnormalities{i-1} = 'P-wave inversion detected';
            showWarning();
        else
            p_wave_abnormalities{i-1} = 'P-wave within normal limits';
            showWarning();
        end
    end
end

        end

        % Callback function
        function UIAxesButtonDown(comp, event)
          %  plot(comp.UIAxes(t(s_locs),ECGsignal(q_locs)));
          %close(patientRec_SECG);
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
           % plot(comp.UIAxes)
        end

        % Create the underlying components
        function setup(comp)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            comp.Position = [1 1 900 500];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.UIAxes2 = uiaxes(comp);
            title(comp.UIAxes2, 'Heart beat')
            xlabel(comp.UIAxes2, 'X')
            ylabel(comp.UIAxes2, 'Y')
            zlabel(comp.UIAxes2, 'Z')
            comp.Image = uiimage(comp);
            comp.Image.ImageSource = fullfile(pathToMLAPP, 'images', 'transparent_image.png');
            comp.Image_2 = uiimage(comp);
            comp.Image_2.ImageSource = fullfile(pathToMLAPP, 'images', 'transparent_image.png');
            comp.BPMHeartRateValue = uilabel(comp);
            comp.BPMHeartRateValue.Text = '67.12 BPM';
            comp.ImageWarningUser = uiimage(comp);
            comp.ImageWarningUser.Visible = 'off';
            comp.ImageWarningUser.ImageSource = fullfile(pathToMLAPP, 'images', 'warning.png');
            comp.errorMessageUser = uilabel(comp);
            comp.errorMessageUser.Visible = 'off';
            comp.errorMessageUser.Text = {'Irregularities detected in ECG kindly '; 'contact your doctor for further information'};
            comp.HeartRateLabel = uilabel(comp);
            comp.HeartRateLabel.Text = 'Heart Rate:';
            comp.Image_3 = uiimage(comp);
            comp.Image_3.ImageSource = fullfile(pathToMLAPP, 'images', 'transparent_image.png');
            comp.Image_4 = uiimage(comp);
            comp.Image_4.ImageSource = fullfile(pathToMLAPP, 'images', 'transparent_image.png');
            comp.BPMHeartRateValue_2 = uilabel(comp);
            comp.BPMHeartRateValue_2.Text = 'Normal Range';
            comp.BPMHeartRateValue_3 = uilabel(comp);
            comp.BPMHeartRateValue_3.Text = 'Abnormal Zone';
            % Execute the startup function
            plotECG(comp)
        end
    end
end