classdef retrivePatientInfo < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        NextButton                    matlab.ui.control.Button
        PatientInformationLabel       matlab.ui.control.Label
        PatientDOBEditField           matlab.ui.control.EditField
        PatientDOBEditFieldLabel      matlab.ui.control.Label
        PatientAdmittedDateEditField  matlab.ui.control.EditField
        PatientAdmittedDateEditFieldLabel  matlab.ui.control.Label
        PatientNameEditField          matlab.ui.control.EditField
        PatientNameEditFieldLabel     matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: NextButton
        function NextButtonPushed(comp, event)
            
            %assign textfilds values 
            patientName= comp.PatientNameEditField;
            patientDob= comp.PatientDOBEditField;
            patientAdmittedDate= comp.PatientAdmittedDateEditField;

            %call next component
            patientRec_SECG;

            %clear pervious textfields value
            patientName= "";
            patientDob= "";
            patientAdmittedDate= "";

        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 530 309];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.PatientNameEditFieldLabel = uilabel(comp);
            comp.PatientNameEditFieldLabel.Text = 'Patient Name';
            comp.PatientNameEditField = uieditfield(comp, 'text');
            comp.PatientAdmittedDateEditFieldLabel = uilabel(comp);
            comp.PatientAdmittedDateEditFieldLabel.Text = 'Patient Admitted Date';
            comp.PatientAdmittedDateEditField = uieditfield(comp, 'text');
            comp.PatientDOBEditFieldLabel = uilabel(comp);
            comp.PatientDOBEditFieldLabel.Text = 'Patient DOB';
            comp.PatientDOBEditField = uieditfield(comp, 'text');
            comp.PatientInformationLabel = uilabel(comp);
            comp.PatientInformationLabel.Text = 'Patient Information ';
            comp.NextButton = uibutton(comp, 'push');
            comp.NextButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @NextButtonPushed, true);
            comp.NextButton.Text = 'Next';
        end
    end
end