classdef userCasesScr < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        ECGSystemLabel   matlab.ui.control.Label
        RelativeButton   matlab.ui.control.Button
        NurseButton      matlab.ui.control.Button
        ParamedicButton  matlab.ui.control.Button
        DoctorButton     matlab.ui.control.Button
    end

    
    methods (Access = public)
        
        function doctorButtonCallback(~, ~)
    % Close the main figure
    close(userCasesScr);
    
    % Create the Doctor page with two buttons
    doctorFig = figure('Position', [100, 100, 400, 300], 'Name', 'Doctor Page');
    
    % Create buttons on the Doctor page
    btnRetrieveData = uicontrol('Style', 'pushbutton', 'String', 'Retrieve Data', ...
        'Position', [50, 200, 150, 30], 'Callback', @retrieveDataButtonCallback);

    btnStartECG = uicontrol('Style', 'pushbutton', 'String', 'Start ECG', ...
        'Position', [50, 150, 150, 30], 'Callback', @startECGButtonCallback);
    
    % Callback function for Retrieve Data button
    function retrieveDataButtonCallback(~, ~)
        disp('Retrieve Data button pressed!');
        % Implement your logic for Retrieve Data here
    end

    % Callback function for Start ECG button
    function startECGButtonCallback(~, ~)
        disp('Start ECG button pressed!');
        % Implement your logic for Start ECG here
    end
end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: NurseButton
        function NurseButtonPushed(app, event)
            
        end

        % Button pushed function: DoctorButton
        function DoctorButtonPushed(app, event)
            RetAndStartEcgScr;
          
        end

        % Window button down function: UIFigure
        function userCaseScrDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @userCaseScrDown, true);
            app.DoctorButton = uibutton(app.UIFigure, 'push');
            app.DoctorButton.ButtonPushedFcn = createCallbackFcn(app, @DoctorButtonPushed, true);
            app.DoctorButton.Text = 'Doctor ';
            app.ParamedicButton = uibutton(app.UIFigure, 'push');
            app.ParamedicButton.Text = 'Paramedic';
            app.NurseButton = uibutton(app.UIFigure, 'push');
            app.NurseButton.ButtonPushedFcn = createCallbackFcn(app, @NurseButtonPushed, true);
            app.NurseButton.Text = 'Nurse';
            app.RelativeButton = uibutton(app.UIFigure, 'push');
            app.RelativeButton.Text = 'Relative';
            app.ECGSystemLabel = uilabel(app.UIFigure);
            app.ECGSystemLabel.Text = 'ECG System';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = userCasesScr

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end